classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        WczytajdaneButton    matlab.ui.control.Button
        UITable              matlab.ui.control.Table
        ObliczButton         matlab.ui.control.Button
        NarysujwykresButton  matlab.ui.control.Button
        UIAxes               matlab.ui.control.UIAxes
        WynikEditFieldLabel  matlab.ui.control.Label
        WynikEditField       matlab.ui.control.NumericEditField
    end

    methods (Access = private)

        % Button pushed function: WczytajdaneButton
        function WczytajdaneButtonPushed(app, event)
            global odcinek1;
            [nazwa] = uigetfile('*.txt');
            odcinek1 = load(nazwa);
            set(app.UITable, 'data', odcinek1);
        end

        % Button pushed function: ObliczButton
        function ObliczButtonPushed(app, event)
            global odcinek1;
            [dshr,temp]=odcinek(odcinek1);
            dshr=num2str(dshr);
            set(app.WynikEditField,'Value',dshr);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.WczytajdaneButton = uibutton(app.UIFigure, 'push');
            app.WczytajdaneButton.ButtonPushedFcn = createCallbackFcn(app, @WczytajdaneButtonPushed, true);
            app.WczytajdaneButton.Text = {'Wczytaj dane'; ''};
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'1'; '2'; '3'; '4'; '5'; '6'};
            app.UITable.RowName = {};
            app.ObliczButton = uibutton(app.UIFigure, 'push');
            app.ObliczButton.ButtonPushedFcn = createCallbackFcn(app, @ObliczButtonPushed, true);
            app.ObliczButton.Text = 'Oblicz';
            app.NarysujwykresButton = uibutton(app.UIFigure, 'push');
            app.NarysujwykresButton.Text = {'Narysuj wykres'; ''};
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Wykres')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.WynikEditFieldLabel = uilabel(app.UIFigure);
            app.WynikEditFieldLabel.Text = 'Wynik';
            app.WynikEditField = uieditfield(app.UIFigure, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end